classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        mLabel                         matlab.ui.control.Label
        AnswerEditField                matlab.ui.control.EditField
        AnswerEditFieldLabel           matlab.ui.control.Label
        ClearButton                    matlab.ui.control.Button
        CalculateButton                matlab.ui.control.Button
        AngleEditField                 matlab.ui.control.NumericEditField
        AngleEditFieldLabel            matlab.ui.control.Label
        HeightofUsermEditField         matlab.ui.control.NumericEditField
        HeightofUsermEditFieldLabel    matlab.ui.control.Label
        HeightofWallmEditField         matlab.ui.control.NumericEditField
        HeightofWallmEditFieldLabel    matlab.ui.control.Label
        DistancetoWallmEditField       matlab.ui.control.NumericEditField
        DistancetoWallmEditFieldLabel  matlab.ui.control.Label
        PlanetDropDown                 matlab.ui.control.DropDown
        PlanetDropDownLabel            matlab.ui.control.Label
        KinematicsCalculatorLabel      matlab.ui.control.Label
        VelocitymsEditField            matlab.ui.control.NumericEditField
        VelocitymsEditFieldLabel       matlab.ui.control.Label
        UIAxes                         matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            V = app.VelocitymsEditField.Value;
            D = app.DistancetoWallmEditField.Value;
            P = app.PlanetDropDown.Value;
            HU = app.HeightofUsermEditField.Value;
            hwall = app.HeightofWallmEditField.Value;
            theta = app.AngleEditField.Value;
           [Cleardist,Fallshort,Vx,Vy,twall,g] = wallkinematics(V,D,P,HU,hwall,theta);
           if Fallshort == 1
               app.AnswerEditFieldLabel.Text = 'The ball did not reach the wall';
           elseif Fallshort == 0
               app.AnswerEditFieldLabel.Text = 'The ball cleared the wall by';
           elseif Fallshort == 2
               app.AnswerEditFieldLabel.Text = 'The ball did not clear the wall by';
           end
           app.AnswerEditField.Value = num2str(Cleardist);
           
hballg=HU;
 wallpos=linspace(0, hwall, 2);
 Dwall=[D,D];
 plot(app.UIAxes,Dwall, wallpos, 'r-', LineWidth=4)
 yline(app.UIAxes, 0,'g-', LineWidth=2)
 hold(app.UIAxes,'on')
 xlim(app.UIAxes, [0, D])
i=1;
 ylim(app.UIAxes, [0, hwall*1.15])
 title(app.UIAxes, 'X and Y Postion of Ball over Time')
 xlabel(app.UIAxes, 'X Position(meters)')
 ylabel(app.UIAxes, 'Y Position(meters)')
for j=0:.1:D*10
    x(i) = Vx*j; %#ok<AGROW>
    hballg(i)=Vy*j+1/2*(-g)*j.^2+HU;
    plot(app.UIAxes,x,hballg, 'b-')
    drawnow
    pause(0.2)
    if hballg(i)<0
        break
    elseif j>twall && (Vy*twall+1/2*(-g)*twall.^2+HU)<hwall
        xlim(app.UIAxes,[0,D])
        break
    end
     if x(i)>D
        xlim(app.UIAxes,[0 x(i)*1.25])
      end
      if max(hballg)>hwall
          ylim(app.UIAxes,[0 max(hballg)*1.15])
      end
     i=i+1;
     xticks(app.UIAxes, 0:1:(Vx*j+20))
     yticks(app.UIAxes, 0:1:(max(hballg)+20))
 end
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
          app.VelocitymsEditField.Value = 0;
          app.DistancetoWallmEditField.Value = 0;
          app.PlanetDropDown.Value = 'Earth';
          app.HeightofUsermEditField.Value = 0;
          app.HeightofWallmEditField.Value = 0;
          app.AngleEditField.Value = 0;
          app.AnswerEditField.Value = '';
          app.AnswerEditFieldLabel.Text = 'Answer:';
          hold(app.UIAxes,'off')
          plot(app.UIAxes, 0,0, 'w')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Position of X and Y over Time')
            xlabel(app.UIAxes, 'X Position(meters)')
            ylabel(app.UIAxes, 'Y Position(meters)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [0 1 2 3 4 5 6 7];
            app.VelocitymsEditFieldLabel = uilabel(app.UIFigure);
            app.VelocitymsEditFieldLabel.Text = 'Velocity (m/s)';
            app.VelocitymsEditField = uieditfield(app.UIFigure, 'numeric');
            app.KinematicsCalculatorLabel = uilabel(app.UIFigure);
            app.KinematicsCalculatorLabel.Text = 'Kinematics Calculator';
            app.PlanetDropDownLabel = uilabel(app.UIFigure);
            app.PlanetDropDownLabel.Text = 'Planet';
            app.PlanetDropDown = uidropdown(app.UIFigure);
            app.PlanetDropDown.Items = {'Earth', 'Mercury', 'Venus', 'Mars', 'Jupiter', 'Saturn', 'Uranus', 'Neptune', 'Moon', 'Titan', 'Sun'};
            app.PlanetDropDown.Value = 'Earth';
            app.DistancetoWallmEditFieldLabel = uilabel(app.UIFigure);
            app.DistancetoWallmEditFieldLabel.Text = 'Distance to Wall (m)';
            app.DistancetoWallmEditField = uieditfield(app.UIFigure, 'numeric');
            app.HeightofWallmEditFieldLabel = uilabel(app.UIFigure);
            app.HeightofWallmEditFieldLabel.Text = 'Height of Wall (m)';
            app.HeightofWallmEditField = uieditfield(app.UIFigure, 'numeric');
            app.HeightofUsermEditFieldLabel = uilabel(app.UIFigure);
            app.HeightofUsermEditFieldLabel.Text = 'Height of User (m)';
            app.HeightofUsermEditField = uieditfield(app.UIFigure, 'numeric');
            app.AngleEditFieldLabel = uilabel(app.UIFigure);
            app.AngleEditFieldLabel.Text = 'Angle';
            app.AngleEditField = uieditfield(app.UIFigure, 'numeric');
            app.CalculateButton = uibutton(app.UIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.ClearButton = uibutton(app.UIFigure, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.AnswerEditFieldLabel = uilabel(app.UIFigure);
            app.AnswerEditFieldLabel.Text = 'Answer:';
            app.AnswerEditField = uieditfield(app.UIFigure, 'text');
            app.mLabel = uilabel(app.UIFigure);
            app.mLabel.Text = ' (m)';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end